classdef WaveTankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        WaveControlTab           matlab.ui.container.Tab
        WaveTypeButtonGroup      matlab.ui.container.ButtonGroup
        Button3                  matlab.ui.control.RadioButton
        Button2                  matlab.ui.control.RadioButton
        TypButton                matlab.ui.control.RadioButton
        PowerSlider              matlab.ui.control.Slider
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ControlWave              matlab.ui.control.UIAxes
        WaveAnalysisTab          matlab.ui.container.Tab
        AnalysisWave             matlab.ui.control.UIAxes
        WaveCalibrationTab       matlab.ui.container.Tab
        HorizontalSlider_3       matlab.ui.control.Slider
        HorizontalSlider_3Label  matlab.ui.control.Label
        VerticalSlider_3         matlab.ui.control.Slider
        VerticalSlider_3Label    matlab.ui.control.Label
        ViewBoxSizeLabel         matlab.ui.control.Label
        EnableSwitchLabel        matlab.ui.control.Label
        EnableSwitch             matlab.ui.control.Switch
        ColorThresholdingLabel   matlab.ui.control.Label
        VerticalSlider_2         matlab.ui.control.Slider
        VerticalSlider_2Label    matlab.ui.control.Label
        HorizontalSlider_2       matlab.ui.control.Slider
        HorizontalSlider_2Label  matlab.ui.control.Label
        AfterImpactBoxLabel      matlab.ui.control.Label
        VerticalSlider           matlab.ui.control.Slider
        HorizontalLabel          matlab.ui.control.Label
        HorizontalSlider         matlab.ui.control.Slider
        HorizontalSliderLabel    matlab.ui.control.Label
        BeforeImpactBoxLabel     matlab.ui.control.Label
        DownSlider               matlab.ui.control.Slider
        DownSliderLabel          matlab.ui.control.Label
        UpSlider                 matlab.ui.control.Slider
        UpSliderLabel            matlab.ui.control.Label
        CropLabel                matlab.ui.control.Label
        RightSlider              matlab.ui.control.Slider
        RightSliderLabel         matlab.ui.control.Label
        LeftSlider               matlab.ui.control.Slider
        LeftSliderLabel          matlab.ui.control.Label
        EditedCalibrationWave_2  matlab.ui.control.UIAxes
        EditedCalibrationWave    matlab.ui.control.UIAxes
        FullCalibrationWave      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setting up elements
            disp(app.UpSlider.Limits)
            app.UpSlider.Limits = [0 1000]
            disp(app.UpSlider.Limits)
            crop1 = 500;
            %crop1 = 1000;
            crop2 = 600;
            crop3 = 1100;
            crop4 = 275;
            
            % Reading the video and starting the videoplayer
            originalVideo = vision.VideoFileReader("wave_03.mov")
            videoPlayer = vision.VideoPlayer;
            i = 0; 
            while 0 < 1
                %Getting crop
                crop1 = app.UpSlider.Value;

                % Showing the original, unedited video
                videoPlayer(originalVideo)
                

                %% Showing and editing the edited video
                    %Creates the mask of the wave
                    %originalVideo = imcrop(originalVideo(), [app.UpSlider.Value, app.LeftSlider.Value,app.DownSlider.Value,app.RightSlider.Value]);
                BWWave = edge(createMask(imgaussfilt(imcrop(originalVideo(), [crop1,crop2,crop3,crop4]),3)), 'canny');
                
                    %No idea what this is for and what it does but it works
                row = nan(1,size(BWWave,2));
                [heightIMG,widthIMG] = size(BWWave);
                line = zeros(heightIMG, widthIMG);

                js = zeros(1, widthIMG-2);
                ls = zeros(1, widthIMG-2);
                for j = 1:widthIMG; 
                    for l = 1:heightIMG %figure out how to get height
                        if BWWave(l,j) == 1
                            line(l, j) = 1;
                            js(j-1) = j;
                            ls(j-1) = l;
                            break
                        end
                    end
                end
                row = fillmissing(row, 'previous');
                % Finding height of calm water
                for k = 1:widthIMG; 
                    try 
                        row(k) = find(BWWave(:,k),1,'first');
                        [lowY, corX] = min(row);
                        [highY, corXHigh] = max(row);
                    end    
                end
                calmLvl = lowY;

                editedVideo = BWWave + imcrop(originalVideo(), [crop1,crop2,crop3,crop4]);
                editedVideo = insertShape(editedVideo, 'circle', [corX lowY 5], 'LineWidth', 10);
                editedVideo = insertShape(editedVideo,'line', [0 calmLvl widthIMG+1 calmLvl], 'LineWidth', 2, 'Color', 'red');
                imshow(editedVideo, 'Parent', app.AnalysisWave)
                imshow(editedVideo, 'Parent', app.ControlWave)
                imshow(editedVideo, 'Parent', app.FullCalibrationWave)

                if isDone(originalVideo) % Restarts the videoplayer when it finishes
                    originalVideo = vision.VideoFileReader("wave_03.mov")
                end
            end
        end

        % Value changing function: UpSlider
        function UpSliderValueChanging(app, event)
            changingValue = event.Value;
            disp(changingValue)
            crop1 = changingValue
            %crop2 = changingValue
            %crop3 = changingValue
            %crop4 = changingValue
        end

        % Value changing function: DownSlider
        function DownSliderValueChanging(app, event)
            changingValue = event.Value;
            crop2 = changingValue
        end

        % Value changing function: LeftSlider
        function LeftSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveControlTab = uitab(app.TabGroup);
            app.WaveControlTab.Title = 'Wave Control';
            app.ControlWave = uiaxes(app.WaveControlTab);
            title(app.ControlWave, 'Wave')
            app.ControlWave.XTick = [];
            app.ControlWave.YTick = [];
            app.StartButton = uibutton(app.WaveControlTab, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.WaveControlTab, 'push');
            app.StopButton.Text = 'Stop';
            app.PowerSlider = uislider(app.WaveControlTab);
            app.WaveTypeButtonGroup = uibuttongroup(app.WaveControlTab);
            app.WaveTypeButtonGroup.Title = 'Wave Type';
            app.TypButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.TypButton.Text = 'Typ';
            app.TypButton.Value = true;
            app.Button2 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button3.Text = 'Button3';
            app.WaveAnalysisTab = uitab(app.TabGroup);
            app.WaveAnalysisTab.Title = 'Wave Analysis';
            app.AnalysisWave = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisWave, 'Wave')
            app.AnalysisWave.XTick = [];
            app.AnalysisWave.YTick = [];
            app.WaveCalibrationTab = uitab(app.TabGroup);
            app.WaveCalibrationTab.Title = 'Wave Calibration';
            app.FullCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.FullCalibrationWave, 'Full Wave')
            app.FullCalibrationWave.XTick = [];
            app.FullCalibrationWave.YTick = [];
            app.EditedCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.EditedCalibrationWave, {'Before Impact'; ''})
            app.EditedCalibrationWave.XTick = [];
            app.EditedCalibrationWave.YTick = [];
            app.EditedCalibrationWave_2 = uiaxes(app.WaveCalibrationTab);
            title(app.EditedCalibrationWave_2, {'After Impact'; ''})
            app.EditedCalibrationWave_2.XTick = [];
            app.EditedCalibrationWave_2.YTick = [];
            app.LeftSliderLabel = uilabel(app.WaveCalibrationTab);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.WaveCalibrationTab);
            app.LeftSlider.ValueChangingFcn = createCallbackFcn(app, @LeftSliderValueChanging, true);
            app.RightSliderLabel = uilabel(app.WaveCalibrationTab);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.WaveCalibrationTab);
            app.CropLabel = uilabel(app.WaveCalibrationTab);
            app.CropLabel.Text = 'Crop';
            app.UpSliderLabel = uilabel(app.WaveCalibrationTab);
            app.UpSliderLabel.Text = 'Up';
            app.UpSlider = uislider(app.WaveCalibrationTab);
            app.UpSlider.ValueChangingFcn = createCallbackFcn(app, @UpSliderValueChanging, true);
            app.DownSliderLabel = uilabel(app.WaveCalibrationTab);
            app.DownSliderLabel.Text = 'Down';
            app.DownSlider = uislider(app.WaveCalibrationTab);
            app.DownSlider.ValueChangingFcn = createCallbackFcn(app, @DownSliderValueChanging, true);
            app.BeforeImpactBoxLabel = uilabel(app.WaveCalibrationTab);
            app.BeforeImpactBoxLabel.Text = 'Before Impact Box';
            app.HorizontalSliderLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalSliderLabel.Text = {'Horizontal'; ''};
            app.HorizontalSlider = uislider(app.WaveCalibrationTab);
            app.HorizontalLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalLabel.Text = 'Vertical';
            app.VerticalSlider = uislider(app.WaveCalibrationTab);
            app.AfterImpactBoxLabel = uilabel(app.WaveCalibrationTab);
            app.AfterImpactBoxLabel.Text = 'After Impact Box';
            app.HorizontalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_2Label.Text = {'Horizontal'; ''};
            app.HorizontalSlider_2 = uislider(app.WaveCalibrationTab);
            app.VerticalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.VerticalSlider_2Label.Text = 'Vertical';
            app.VerticalSlider_2 = uislider(app.WaveCalibrationTab);
            app.ColorThresholdingLabel = uilabel(app.WaveCalibrationTab);
            app.ColorThresholdingLabel.Text = 'Color Thresholding';
            app.EnableSwitch = uiswitch(app.WaveCalibrationTab, 'slider');
            app.EnableSwitchLabel = uilabel(app.WaveCalibrationTab);
            app.EnableSwitchLabel.Text = 'Enable ';
            app.ViewBoxSizeLabel = uilabel(app.WaveCalibrationTab);
            app.ViewBoxSizeLabel.Text = 'View Box Size';
            app.VerticalSlider_3Label = uilabel(app.WaveCalibrationTab);
            app.VerticalSlider_3Label.Text = 'Vertical';
            app.VerticalSlider_3 = uislider(app.WaveCalibrationTab);
            app.HorizontalSlider_3Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_3Label.Text = {'Horizontal'; ''};
            app.HorizontalSlider_3 = uislider(app.WaveCalibrationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end